classdef MEETFLIP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MaxDiametermmEditField         matlab.ui.control.NumericEditField
        MaxDiametermmEditFieldLabel    matlab.ui.control.Label
        MaximumDiameterCheckBox        matlab.ui.control.CheckBox
        MedianCSACheckBox              matlab.ui.control.CheckBox
        TabGroup2                      matlab.ui.container.TabGroup
        TableofDistentionVolumes       matlab.ui.container.Tab
        PlotVolumeasaFunctionofTimeButton  matlab.ui.control.Button
        DiameterCSACalculatorPanel     matlab.ui.container.Panel
        ConvertButton                  matlab.ui.control.Button
        CSAmm2EditField                matlab.ui.control.NumericEditField
        CSAmm2EditFieldLabel           matlab.ui.control.Label
        DiametermmEditField            matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel       matlab.ui.control.Label
        RemoveButton                   matlab.ui.control.Button
        DistentionVolumemLEditField    matlab.ui.control.NumericEditField
        DistentionVolumemLEditFieldLabel  matlab.ui.control.Label
        AddDistentionButton            matlab.ui.control.Button
        DistentionTable                matlab.ui.control.Table
        volumePlot                     matlab.ui.control.UIAxes
        DiameterAnalysisTab            matlab.ui.container.Tab
        CopytoClipboardButton          matlab.ui.control.Button
        average_median_min             matlab.ui.control.NumericEditField
        average_mean_min               matlab.ui.control.NumericEditField
        AverageLabel                   matlab.ui.control.Label
        pValueLabel                    matlab.ui.control.Label
        p_median_min                   matlab.ui.control.NumericEditField
        H0MedianMin0EditFieldLabel     matlab.ui.control.Label
        HypothesisLabel                matlab.ui.control.Label
        p_mean_min                     matlab.ui.control.NumericEditField
        H0MeanMin0EditFieldLabel       matlab.ui.control.Label
        AnalyzeButton                  matlab.ui.control.Button
        DistentionVolumesmLListBox     matlab.ui.control.ListBox
        DistentionVolumesmLListBoxLabel  matlab.ui.control.Label
        tableForStats                  matlab.ui.control.Table
        SummaryofDiametersatDistentionVolumesTab  matlab.ui.container.Tab
        CopytoClipboardButton_2        matlab.ui.control.Button
        diameterSummarytable           matlab.ui.control.Table
        GenerateSummaryofDiametersButton  matlab.ui.control.Button
        AveragesofMedianMinDiametersLabel  matlab.ui.control.Label
        Volume70ccMedian_Min           matlab.ui.control.NumericEditField
        ccLabel_8                      matlab.ui.control.Label
        Volume60ccMedian_Min           matlab.ui.control.NumericEditField
        ccLabel_7                      matlab.ui.control.Label
        Volume50ccMedian_Min           matlab.ui.control.NumericEditField
        ccLabel_6                      matlab.ui.control.Label
        Volume40ccMedian_Min           matlab.ui.control.NumericEditField
        ccLabel_5                      matlab.ui.control.Label
        Volume30ccMedian_Min           matlab.ui.control.NumericEditField
        ccEditFieldLabel_2             matlab.ui.control.Label
        AveragesofMeanMinDiametersLabel  matlab.ui.control.Label
        Volume70ccMean_Min             matlab.ui.control.NumericEditField
        ccLabel_4                      matlab.ui.control.Label
        Volume60ccMean_Min             matlab.ui.control.NumericEditField
        ccLabel_3                      matlab.ui.control.Label
        Volume50ccMean_Min             matlab.ui.control.NumericEditField
        ccLabel_2                      matlab.ui.control.Label
        Volume40ccMean_Min             matlab.ui.control.NumericEditField
        ccLabel                        matlab.ui.control.Label
        Volume30ccMean_Min             matlab.ui.control.NumericEditField
        ccEditFieldLabel               matlab.ui.control.Label
        ElectrodeSignalsandDistensibilityPlotsTab  matlab.ui.container.Tab
        TimeRange_Text_2               matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        bottom_plot                    matlab.ui.control.UIAxes
        top_plot                       matlab.ui.control.UIAxes
        ShowEsophagealTopographyPlotButton  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        IntrabagPressureasaFunctionofIntrabagVolumeTab  matlab.ui.container.Tab
        pressureVSvolume_plot          matlab.ui.control.UIAxes
        OverallDistensibilityTab       matlab.ui.container.Tab
        overallDI_plot                 matlab.ui.control.UIAxes
        SelecttoPlotPanel              matlab.ui.container.Panel
        PopulateTableButton            matlab.ui.control.Button
        PlotCSAasaFunctionofPressureButton  matlab.ui.control.Button
        PlotSignalsasaFunctionofTimeButton  matlab.ui.control.Button
        PressureCheckBox               matlab.ui.control.CheckBox
        MeanofElectrodesCheckBox       matlab.ui.control.CheckBox
        Electrode16CheckBox            matlab.ui.control.CheckBox
        Electrode15CheckBox            matlab.ui.control.CheckBox
        Electrode14CheckBox            matlab.ui.control.CheckBox
        Electrode13CheckBox            matlab.ui.control.CheckBox
        Electrode12CheckBox            matlab.ui.control.CheckBox
        Electrode11CheckBox            matlab.ui.control.CheckBox
        Electrode10CheckBox            matlab.ui.control.CheckBox
        Electrode9CheckBox             matlab.ui.control.CheckBox
        Electrode8CheckBox             matlab.ui.control.CheckBox
        Electrode7CheckBox             matlab.ui.control.CheckBox
        Electrode6CheckBox             matlab.ui.control.CheckBox
        Electrode5CheckBox             matlab.ui.control.CheckBox
        Electrode4CheckBox             matlab.ui.control.CheckBox
        Electrode3CheckBox             matlab.ui.control.CheckBox
        Electrode2CheckBox             matlab.ui.control.CheckBox
        Electrode1CheckBox             matlab.ui.control.CheckBox
        UserInputPanel                 matlab.ui.container.Panel
        TimeminEditField               matlab.ui.control.NumericEditField
        TimeminEditFieldLabel          matlab.ui.control.Label
        SaveFrameButton                matlab.ui.control.Button
        PlayVideoButton                matlab.ui.control.Button
        time2_Entry                    matlab.ui.control.NumericEditField
        ToLabel                        matlab.ui.control.Label
        time1_Entry                    matlab.ui.control.NumericEditField
        FromLabel                      matlab.ui.control.Label
        TimeRange_Text                 matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        StartUploadFIleButton          matlab.ui.control.Button
        SearchFolderButton             matlab.ui.control.Button
        EnterFileName                  matlab.ui.control.EditField
        EntertxtfilenameandhitstartLabel  matlab.ui.control.Label
        file_options                   matlab.ui.control.DropDown
        FolderDirectoryEditField       matlab.ui.control.EditField
        FolderDirectoryEditFieldLabel  matlab.ui.control.Label
        heatmap_plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        diameter_recordings_raw; %this is only to access the raw data of the recordings (full datase)
        pressures_raw;
        volumes_raw; 
        %these are the median recordings for each second 
        median_recordings; %these are diameter measurements (median diameter for each second)
        median_pressures;
        median_fills
        time;
%         stat0cc_accumulated;
%         stat30cc_accumulated;
%         stat40cc_accumulated;
%         stat50cc_accumulated;
%         stat60cc_accumulated;
%         stat70cc_accumulated;
        time_unique;
        fileName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartUploadFIleButton
        function StartUploadFIleButtonPushed(app, event)
% % clears figures
cla(app.top_plot);
cla(app.bottom_plot);
cla(app.heatmap_plot);
cla(app.volumePlot);
cla(app.overallDI_plot);
cla(app.pressureVSvolume_plot);

% processes the name of the .txt file file
app.fileName = app.EnterFileName.Value; %this is the name of the file
file_folder = app.FolderDirectoryEditField.Value; %this is the inputted directory
raw_data = readtable(fullfile(file_folder, app.fileName),'PreserveVariableNames',true);
raw_data = table2cell(raw_data);

% initializes variables from large data set
% raw_data(1,:) = []; %titles preserved in table called live_data_copy
raw_times = raw_data(:,1:1); %these are the raw times in characters
diameter_recordings = raw_data(:,4:19); %CSA_recordings is  a matrix of 16 columns representing the different electrodes and each row is a different recording at a different time
diameter_recordings = cell2mat(diameter_recordings);
balloon_fill = raw_data(:,21:21); %balloon fill is the same as balloon volume
balloon_fill = cell2mat(balloon_fill);
balloon_pressure = raw_data(:,20:20);
balloon_pressure = cell2mat(balloon_pressure);

%computes start time in seconds after processing of raw data/strings
 x = raw_times(1,1);
 x = convertCharsToStrings(x);
 hours = extractBetween(x,12,13);
 minutes = extractBetween(x,15,16);
 seconds = extractBetween(x,18,19);
 start_time = str2double(hours)*60*60 + str2double(minutes)*60 + str2double(seconds); %in seconds

%extract time from start in seconds from raw dates 
time_seconds = zeros(size(diameter_recordings,1), 1);

for n = 1:size(diameter_recordings,1)
    a = raw_times(n,1);
    a = convertCharsToStrings(a);
    hours = extractBetween(a,12,13);
    minutes = extractBetween(a,15,16);
    seconds = extractBetween(a,18,19);
    time_in_seconds = str2double(hours)*60*60 + str2double(minutes)*60 + str2double(seconds); %in seconds
    time_seconds(n,1) = time_in_seconds - start_time; %time is in seconds from start
end

time_seconds_unique = min(time_seconds):max(time_seconds); %start from zero second to max second
% find the median measurement for each second
median_diameters = zeros(length(time_seconds_unique),size(diameter_recordings,2)); 
median_volumes = zeros(length(time_seconds_unique),1);
median_mmHg = zeros(length(time_seconds_unique),1);

for m = min(time_seconds):max(time_seconds) %starting at time of 1 second %there is no second 1709
    %median recording in each second
    one_second_positions = find(time_seconds == m);
    data_one_second = diameter_recordings(one_second_positions,:);
    median_diameters(m+1,:) = median(data_one_second); %by default the first dimension is taken
    %median fill in each second
    one_second_balloon_fill = balloon_fill(one_second_positions);
    median_volumes(m+1) = median(one_second_balloon_fill);
    %median pressure in each second
    one_second_balloon_pressure = balloon_pressure(one_second_positions);
    median_mmHg(m+1) = median(one_second_balloon_pressure);
end

%user validation of start to end time
begin_time = app.time1_Entry.Value * 60; %converts the time input from user to seconds
finish_time = app.time2_Entry.Value * 60;


% makes sure the user time range are within time limits
% begin time cannot be 0; minimum time is 1
if begin_time < min(time_seconds) || begin_time == 0
    begin_time = min(time_seconds);
end
if isinteger(begin_time) == 0
    begin_time = ceil(begin_time); %seconds has to be an integer
end
if isinteger(finish_time) == 0
    begin_time = ceil(begin_time); %second has to be an integer
end
if finish_time > max(time_seconds) || finish_time == 0
    finish_time = max(time_seconds);
end

%changes the slider values of time
% time_seconds_unique = begin_time:finish_time;
app.Slider.Limits = [begin_time/60 finish_time/60]; %sets the limits of the slider in minutes

%after extraction and processing, save the recordings that can be called into other
%functions
%raw signals
idx = find(time_seconds >= begin_time & time_seconds <= finish_time);
app.time = time_seconds(idx);
app.diameter_recordings_raw = diameter_recordings(idx,:); 
app.pressures_raw = balloon_pressure(idx);
app.volumes_raw = balloon_fill(idx);

%This is only used to generate the video and frames; COMMENT FOR NOW
app.time_unique = time_seconds_unique;
app.median_recordings = median_diameters;
app.median_fills = median_volumes; 
app.median_pressures = median_mmHg; 


%% I don't think I'll use this %%
% index = min(app.time):max(app.time);
% %these are all the same number of rows 
% app.time_unique = time_seconds_unique(index+1);
% app.median_recordings = median_diameters(index+1,:);
% app.median_fills = median_volumes(index+1); 
% app.median_pressures = median_mmHg(index+1); 

%sets up the table with distention volumes
volumes_unique = unique(balloon_fill);
volumes_list = NaN(length(volumes_unique),1); % to use in table
for i = 1:length(volumes_list)
    if sum(balloon_fill == volumes_unique(i)) > 300 %0.5 minutes is 30 seconds. sampling frequency is 10 Hz
        volumes_list(i,1) = volumes_unique(i);
    end
end
volumes_list = rmmissing(volumes_list);
app.DistentionTable.Data = [volumes_list, NaN(length(volumes_list),6)];
app.average_median_min.Value = 0;
app.average_mean_min.Value = 0;
app.p_mean_min.Value = 0;
app.p_median_min.Value = 0;
app.Lamp.Color = 'green'; 

        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
app.Lamp.Color = 'red'; 
% time input from user
begin_time = app.time1_Entry.Value * 60; %converts the time input from user to seconds
finish_time = app.time2_Entry.Value * 60;


% makes sure the user time range are within time limits
% begin time cannot be 0; minimum time is 1
if begin_time < min(app.time) || begin_time == 0
    begin_time = min(app.time);
end
if isinteger(begin_time) == 0
    begin_time = ceil(begin_time);
end
if isinteger(finish_time) == 0
    begin_time = ceil(begin_time);
end
if finish_time > max(app.time) || finish_time == 0
    finish_time = max(app.time);
end
%other variables defined as private properties and used in callback 
distinct_times = app.time_unique; %these are the times in seconds
median_diameters = app.median_recordings;
median_mmHg = app.median_pressures;
% string to specify formatting of number 
formatSpec = '%.1f';

%initializes structure
num_frames_video = finish_time - begin_time + 1;
video(num_frames_video) = struct('cdata',[],'colormap',[]);
frame_number = 1;
%from the start time to the end time
for k = begin_time : finish_time
    clf;
    figure('Visible',"off");
%%%%%%%%%%%%%%%%% make graph on TOP of interface %%%%%%%%%%%
    h = -150; %150 mm = 15 cm
    second_position = find(distinct_times == k);
    r = median_diameters(second_position:second_position,:)/2; %converts diameter to radii
    [X, Y, Z] = cylinder(r);
    Z = Z*h;
%     pressure = median_mmHg(second_position);
    C = ones(size(Z)); %color of cylinders
%     for i = 1:size(median_diameters, 2)
%         C(i,:) = r(i)^2*pi()/pressure * ones(1,size(X,2));
%     end
for i = 1:size(median_diameters, 2)
    C(i,:) = r(i)^2*pi() * ones(1,size(X,2)); %the color is representative of the CSA
end
    tiledlayout(5,5);
    nexttile([3,5]);
    surf(X, Y, Z, C);
    hold on;
    minute = k/60;
    title({['Balloon Pressure (mmHg) ', num2str(median_mmHg(second_position),formatSpec)]; ['Minute ', num2str(minute, formatSpec)]; ['Min | Max CSA (mm^2): ', num2str((min(r))^2*pi(), formatSpec), ' | ', num2str((max(r))^2*pi(), formatSpec)]; ['Mean | Median CSA (mm^2): ', num2str((mean(r))^2*pi(), formatSpec), ' | ' num2str((median(r))^2*pi(), formatSpec)]});
    
%     DI_CS = r.^2*pi()/pressure;

    % colorbar and features
    xlabel('X (mm)');
    ylabel('Y (mm)');
    zlabel('-Z (mm)');
    c = colorbar;
%     caxis([floor(min(DI_CS)) ceil(max(DI_CS))]); %range of colors is DI values of a single time recording
    caxis([floor(min(min(median_diameters))) ceil(max(max(median_diameters)))]); %range of colors is diameter values of all the time points

%     c.Label.String = 'Distensibility (mm^2/mmHg)';
    c.Label.String = 'Diameter mm';
    xlim([(max(max(median_diameters)) * -1) max(max(median_diameters))]);
    ylim([(max(max(median_diameters)) * -1) max(max(median_diameters))]);
    hold off;
    
    
    %%%%%%%%%%%%%%%%% make graph on BOTTOM of interface %%%%%%%%%%%

    nexttile([2,5]);
    % plot for time vs balloon volume
    yyaxis left;
    %shading of regions of critical volumes
    % for the 0 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [0 1 1 0];
    fill(x,y, [0.9 0.9 0.9],'LineStyle', 'none','Marker','none');
    hold on;
    %for the 30 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [29 31 31 29];
    fill(x,y,[0.9 0.9 0.9], 'LineStyle', 'none','Marker','none');
    %for the 40 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [39 41 41 39];
    fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none','Marker','none');
    %for the 50 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [49 51 51 49];
    fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none','Marker','none');
    %for the 60 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [59 61 61 59];
    fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none','Marker','none');
    %for the 70 cc region
    x = [min(app.time) min(app.time) max(app.time) max(app.time)];
    y = [69 71 71 69];
    fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none','Marker','none');
    
    % median fills vs time plot
    plot(distinct_times/60, app.median_fills, '-b');
    ylabel('Balloon Fill (cc)', 'Color', 'b');
    xlim([min(app.time)/60 max(app.time)/60]);
    ylim([min(app.median_fills)-5 max(app.median_fills)+5]);
    set(gca,'ycolor','b')

    % pressure vs time plot
    yyaxis right;
    plot(app.time_unique/60, median_mmHg, '-r');
    xlim([min(app.time)/60 max(app.time)/60]);
    ylim([min(median_mmHg)-5 max(median_mmHg)+5]);
    ylabel('Balloon Pressure (mmHg)', 'Color', 'r');
    set(gca,'ycolor','r')
    %more graphing features
    xlabel('Time (min)');
    title('Balloon Pressure and Volume vs Time');
    xline(distinct_times(second_position)/60);
   
    hold off;

    %saving frame
    drawnow
    video(frame_number) = getframe(gcf); %get current figure (gcf)
    frame_number = frame_number + 1; 
    
end
close all;
app.Lamp.Color = 'green'; 
implay(video, 5); %plays the video at a certain frame count per second

        end

        % Button pushed function: SaveFrameButton
        function SaveFrameButtonPushed(app, event)
% string to specify formatting of number 
formatSpec = '%.1f';
% variables saved to application from Start/Upload callback is used here
distinct_times = app.time_unique;
median_diameters = app.median_recordings;
median_mmHg = app.median_pressures;

k = app.TimeminEditField.Value*60;
if k > max(app.time)
    k = max(app.time);
end
if isinteger(k) == 0
    k = ceil(k);
end

%%%%%%%%%%%%%%%%% make graph on TOP of interface %%%%%%%%%%%
h = -150;
second_position = find(distinct_times == k);
r = median_diameters(second_position:second_position,:)/2; %converts diameter to radii
[X, Y, Z] = cylinder(r);
Z = Z*h; %Scales Z to the length of the balloon
% pressure = app.median_pressures(second_position);
C = ones(size(Z)); %color of cylinders
for i = 1:size(app.median_recordings, 2)
%     C(i,:) = r(i)^2*pi()/pressure * ones(1,size(X,2));
%     C(i,:) = r(i)^2*pi() * ones(1,size(X,2)); %the color is representative of the CSA
    C(i,:) = r(i)* 2 * ones(1,size(X,2)); %the color is representative of the diameter

end
tiledlayout(5,5);
nexttile([3,5]);
surf(X, Y, Z, C);
hold on;
minute = k/60;
title({['Balloon Pressure (mmHg) ', num2str(median_mmHg(second_position),formatSpec)]; ['Minute ', num2str(minute, formatSpec)]; ['Min | Max CSA (mm^2): ', num2str((min(r))^2*pi(), formatSpec), ' | ', num2str((max(r))^2*pi(), formatSpec)]; ['Mean | Median CSA (mm^2): ', num2str((mean(r))^2*pi(), formatSpec), ' | ' num2str((median(r))^2*pi(), formatSpec)]});

% colorbar and features
xlabel('X (mm)');
ylabel('Y (mm)');
zlabel('-Z (mm)');
c = colorbar;
% caxis([floor((min(min(median_diameters))/2)^2*pi()) ceil((max(max(median_diameters))/2)^2*pi())]); %range of colors is DI values of a single time recording
% caxis([floor(min(min(median_diameters))) ceil(max(max(median_diameters)))]); %range of colors is diameter values of all the time points
caxis([0 25]); %range of colors is diameter values of all the time points

% c.Label.String = 'CSA (mm^2)';
c.Label.String = 'Diameter (mm)';

xlim([(max(max(median_diameters)) * -1) max(max(median_diameters))]);
ylim([(max(max(median_diameters)) * -1) max(max(median_diameters))]);
hold off;


%%%%%%%%%%%%%%%%% make graph on BOTTOM of interface %%%%%%%%%%%

nexttile([2,5]);
% plot for time vs balloon volume
yyaxis left;
%shading of regions of critical volumes
% for the 0 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [0 1 1 0];
fill(x,y, [0.9 0.9 0.9],'LineStyle', 'none', 'Marker','none');
hold on;
%for the 30 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [29 31 31 29];
fill(x,y,[0.9 0.9 0.9], 'LineStyle', 'none', 'Marker','none');
%for the 40 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [39 41 41 39];
fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none', 'Marker','none');
%for the 50 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [49 51 51 49];
fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none', 'Marker','none');
%for the 60 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [59 61 61 59];
fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none', 'Marker','none');
%for the 70 cc region
x = [min(app.time) min(app.time) max(app.time) max(app.time)];
y = [69 71 71 69];
fill(x,y,[0.9 0.9 0.9],'LineStyle', 'none', 'Marker','none');

% median fills vs time plot
plot(distinct_times/60, app.median_fills, '-b');
ylabel('Balloon Fill (cc)', 'Color', 'b');
xlim([min(app.time)/60 max(app.time)/60]);
ylim([min(app.median_fills)-5 max(app.median_fills)+5]);
set(gca,'ycolor','b')

% pressure vs time plot
yyaxis right;
plot(app.time_unique/60, median_mmHg, '-r');
xlim([min(app.time)/60 max(app.time)/60]);
ylim([min(app.median_pressures)-5 max(app.median_pressures)+5]);
ylabel('Balloon Pressure (mmHg)', 'Color', 'r');
set(gca,'ycolor','r')
%more graphing features
xlabel('Time (min)');
title('Balloon Pressure and Volume vs Time');
xline(distinct_times(second_position)/60);
hold off;

image = getframe(gcf);
minute = round(k/60, 2); %rounds to two decimal places
minute_string = num2str(minute);
folder_name = erase(app.fileName, '.TXT');
%saving; makes a folder if there is not one already
if not(isfolder(folder_name))
    mkdir(folder_name)
end
image_name = erase(app.fileName, '.TXT');
image_name = strcat(image_name,'MIN',minute_string);
named_File = [image_name, '.png'];
fullDestination = fullfile(folder_name,named_File);
imwrite(image.cdata, fullDestination);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue, 2); %rounds to two decimal places
            app.TimeminEditField.Value = changingValue; 
        end

        % Button pushed function: PlotSignalsasaFunctionofTimeButton
        function PlotSignalsasaFunctionofTimeButtonPushed(app, event)
clf(app.top_plot);
checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16, app.MeanofElectrodesCheckBox.Value*17];
checked_items(checked_items == 0) = [];
time_original = app.time;
diameter_recordings = app.diameter_recordings_raw; 
diameter_recordings = [diameter_recordings, mean(diameter_recordings,2)];
raw_pressures = app.pressures_raw;

%plotting colors 
colors_list = [0.9020 0.0980 0.2941; 0.2353 0.7059 0.2941; 1.0000 0.8824 0.0980; 0 0.5098 0.7843; 0.9608 0.5098 0.1882;  0.2745 0.9412 0.9412; 0.8235 0.9608 0.2353; 0.9804 0.7451 0.8314; 0 0.5020 0.5020; 0.8627 0.7451 1.0000; 0.6667 0.4314 0.1569; 0.5020 0 0; 0.6667 1.0000 0.7647; 0.5020 0.5020 0; 1.0000 0.8431 0.7059; 0 0 0.5020; 0 0 0; 0.47 0.32 0.66];
string = {'E1'; 'E2'; 'E3'; 'E4'; 'E5'; 'E6'; 'E7'; 'E8'; 'E9'; 'E10'; 'E11'; 'E12'; 'E13'; 'E14'; 'E15'; 'E16'; 'Mean of Electrodes'; 'Pressure'};
% https://github.com/jkitchin/matlab-cmu/blob/master/%2Bcmu/colors.m

% plots on the y axis on the left
yyaxis(app.top_plot, 'left');
for k = checked_items
    signal = diameter_recordings(:,k); 
    plot(app.top_plot, time_original/60, signal, '-', 'Color', colors_list(k,:));
    hold(app.top_plot, 'on');    
end
ylabel(app.top_plot, 'CS Diameter (mm)', 'Color', 'k');
app.top_plot.YColor = 'k';

yyaxis(app.top_plot, 'right');
app.top_plot.YColor = colors_list(18,:);

if app.PressureCheckBox.Value == 1
    checked_items = [checked_items, 18];
    plot(app.top_plot, time_original/60, raw_pressures, '-', 'Color', colors_list(18,:));
    ylabel(app.top_plot, 'Intrabag Pressure (mmHg)', 'Color',colors_list(18,:));
end
legend(app.top_plot, string(checked_items),'Location','northwest');
title(app.top_plot, 'Original Signals');
xlabel(app.top_plot, 'Time (min)');
xlim(app.top_plot, [min(time_original)/60 max(time_original)/60]);
hold(app.top_plot, 'off');

close all;

        end

        % Button pushed function: ShowEsophagealTopographyPlotButton
        function ShowEsophagealTopographyPlotButtonPushed(app, event)

%this will generate the heatmap. the x axis is volume. the y axis is
%electrodes 1:16, and the colors of the boxes will represent the cross-sectional areas
%variables to use in this function; referencing startup command
diameter_recordings = app.diameter_recordings_raw; 
raw_pressures = app.pressures_raw;
raw_volumes = app.volumes_raw;

%converts diameter recordings into CSAs
CSA_recordings = (diameter_recordings./2).^2.*pi(); 

% colors used for the electrodes throughout the application 
colors_list = [0.9020 0.0980 0.2941; 0.2353 0.7059 0.2941; 1.0000 0.8824 0.0980; 0 0.5098 0.7843; 0.9608 0.5098 0.1882;  0.2745 0.9412 0.9412; 0.8235 0.9608 0.2353; 0.9804 0.7451 0.8314; 0 0.5020 0.5020; 0.8627 0.7451 1.0000; 0.6667 0.4314 0.1569; 0.5020 0 0; 0.6667 1.0000 0.7647; 0.5020 0.5020 0; 1.0000 0.8431 0.7059; 0 0 0.5020; 0 0 0];

%performs required calculations of raw data before plotting
raw_volumes_unique = unique(raw_volumes);
median_mmHg = NaN(length(raw_volumes_unique),1);
median_CSAs = NaN(length(raw_volumes_unique), size(CSA_recordings, 2));
maximumDiameters = NaN(length(raw_volumes_unique), size(CSA_recordings, 2));

i = 1;
for n = 1: length(raw_volumes_unique)
    indexes = find(raw_volumes == raw_volumes_unique(n));
    median_mmHg(i,1) = median(raw_pressures(indexes));
    median_CSAs(i,:) = median(CSA_recordings(indexes,:),1);
    maximumDiameters(i,:) = max(diameter_recordings(indexes,:), [], 1);
    i = i+1;
end

allElecs = 1:16;
%considers user inputs
checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16];
checked_items(checked_items == 0) = []; 

if isempty(checked_items) == 1 %if empty/ no selection
    checked_items = 1:16; % by default all electrodes are checked 
end

excludedElecs = allElecs; 
excludedElecs(ismember(allElecs, checked_items)) = []; %removes checked items from list
distentionVolumes = [30, 40, 50, 60, 70]; %notes all the distention volumes 

% excludedDistentionVolumes = raw_volumes_unique;
% excludedDistentionVolumes(ismember(raw_volumes_unique, distentionVolumes)) = []; %eliminates the included distention volumes
% 
% %finds the positions of excluded distentions
% % initializes vector to contain index of positions values
% indexesVolumesExcluded = NaN(length(excludedDistentionVolumes),1);
% 
% for i = 1:length(excludedDistentionVolumes)
%     indexesVolumesExcluded(i) = find(raw_volumes_unique == excludedDistentionVolumes(i));
% end

% %heatmap for specified volumes only
% if app.SpecifiedDistentionsCheckBox.Value == 1
%     %formats heatmap for plotting
%     heatmap_data = transpose(median_CSAs);
%     heatmap_data(excludedElecs,:) = NaN; %excludes all the electrodes that are not selected
%     heatmap_data(:,indexesVolumesExcluded) = NaN;
%     heatmap_data = rmmissing(heatmap_data, 2, "MinNumMissing", 16); %eliminates distention columns if all electrodes are missing
%     %heatmap plotting
%     imagesc(app.heatmap_plot, heatmap_data);
%     ax = gca;
%     hold(app.heatmap_plot, 'on');
%     c = colorbar(app.heatmap_plot);
%     c.Title.String = 'Median CSA (mm^2)';
%     title(app.heatmap_plot,'Specific Distentions : Esophageal Topography Plot');
%     xlabel(app.heatmap_plot, 'Intrabag Volume (mL)');
%     app.heatmap_plot.XColor = 'b';
%     ylabel(app.heatmap_plot, 'Electrode #');
%     set(ax, 'XTick', distentionVolumes, 'YTick', 1:16);
%     arrayfun(@(y)yline(app.heatmap_plot,y,'k-','Alpha',0.2),0.5:1:16.5); %alpha sets opacity and transparency
% % %     arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',0.2),0.5:1: max(raw_volumes_unique) + 0.5); %I changed this from 1.5 to 0.5
%     arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',1),[distentionVolumes + 0.5; distentionVolumes+1.5]); %highlights the distention volumes specifically
% %     xlim(app.heatmap_plot, [min(distentionVolumes)+0.5 max(distentionVolumes)+0.5]);
%     ylim(app.heatmap_plot, [0.5 16.5]);
%     yticks(app.heatmap_plot, 1:16);
%     hold(app.heatmap_plot, 'off');
% end

% heatmap based on median CSA
if app.MaxDiametermmEditField.Value == 0
    app.MaxDiametermmEditField.Value = 25;
end
maxDiameterColor = app.MaxDiametermmEditField.Value;

if app.MedianCSACheckBox.Value == 1 
    heatmap_data = transpose(median_CSAs);
    heatmap_data(excludedElecs,:) = NaN; %excludes all the electrodes that are not selected
    imagesc(app.heatmap_plot, heatmap_data, [0 (maxDiameterColor/2)^2*pi()]); %maximum diameter is 25 mm
    hold(app.heatmap_plot, 'on');
    c = colorbar(app.heatmap_plot);
    c.Title.String = 'Median CSA (mm^2)';
    title(app.heatmap_plot,'Esophageal Topography Plot');
    xlabel(app.heatmap_plot, 'Intrabag Volume (mL)');
    app.heatmap_plot.XColor = 'b';
    ylabel(app.heatmap_plot, 'Electrode #');
%     set(ax, 'XTick', raw_volumes_unique, 'YTick', 1:16); 
%     set(gca, 'XTick', 1:length(raw_volumes_unique)+1);
%     set(gca, 'XTickLabel', raw_volumes_unique);
%     set(gca, 'YTick', 1:16); 
    app.heatmap_plot.XTick = 1:length(raw_volumes_unique)+1;
    app.heatmap_plot.XTickLabel = raw_volumes_unique;
    app.heatmap_plot.YTick = 1:16; 
    arrayfun(@(y)yline(app.heatmap_plot,y,'k-','Alpha',0.2),0.5:1:16.5); %alpha sets opacity and transparency
    arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',0.2),0.5:1: max(raw_volumes_unique) + 1.5); %I changed this from 1.5 to 0.5
    arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',1),[distentionVolumes + 0.5; distentionVolumes+1.5]); %highlights the distention volumes specifically
    xlim(app.heatmap_plot, [min(raw_volumes_unique)+0.5 max(raw_volumes_unique)+1.5]);
    ylim(app.heatmap_plot, [0.5 16.5]);
    yticks(app.heatmap_plot, 1:16);
    hold(app.heatmap_plot, 'off');
end 

%heatmap based on maximum diameter
if app.MaximumDiameterCheckBox.Value == 1
    heatmap_data = transpose(maximumDiameters);
    heatmap_data(excludedElecs,:) = NaN; %excludes all the electrodes that are not selected
    imagesc(app.heatmap_plot, heatmap_data, [0 maxDiameterColor]); %maximum in colorbar
    hold(app.heatmap_plot, 'on');
    c = colorbar(app.heatmap_plot);
    c.Title.String = 'Max Diameter (mm)';
    title(app.heatmap_plot,'Esophageal Topography Plot');
    xlabel(app.heatmap_plot, 'Intrabag Volume (mL)');
    app.heatmap_plot.XColor = 'b';
    ylabel(app.heatmap_plot, 'Electrode #');
    app.heatmap_plot.XTick = 1:length(raw_volumes_unique)+1;
    app.heatmap_plot.XTickLabel = raw_volumes_unique;
    app.heatmap_plot.YTick = 1:16; 
    arrayfun(@(y)yline(app.heatmap_plot,y,'k-','Alpha',0.2),0.5:1:16.5); %alpha sets opacity and transparency
    arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',0.2),0.5:1: max(raw_volumes_unique) + 1.5); 
    arrayfun(@(x)xline(app.heatmap_plot,x,'k-','Alpha',1),[distentionVolumes + 0.5; distentionVolumes+1.5]); %highlights the distention volumes specifically
    xlim(app.heatmap_plot, [min(raw_volumes_unique)+0.5 max(raw_volumes_unique)+1.5]); 
    ylim(app.heatmap_plot, [0.5 16.5]);
    yticks(app.heatmap_plot, 1:16);
    hold(app.heatmap_plot, 'off');
end


%FIGURE for tab1
scatter(app.pressureVSvolume_plot, raw_volumes_unique, median_mmHg);
ylabel(app.pressureVSvolume_plot, 'Intrabag Pressure (mmHg)', 'Color', [0.47 0.32 0.66]);
app.pressureVSvolume_plot.YColor = [0.47 0.32 0.66];
xlabel(app.pressureVSvolume_plot, 'Intrabag Volume (mL)');
app.pressureVSvolume_plot.XColor = 'b'; 
title(app.pressureVSvolume_plot, 'Pressure as a Function of Volume');

%FIGURE for tab2
%performs required calculations of raw data before plotting
%computing distensibility of all 16 regions
distensibility = NaN(size(CSA_recordings, 2),1); %initializes vector to be filled by for loop
%performs required calculations of raw data before plotting
distention_volumes = app.DistentionTable.Data(:,1); %distention volumes specified in table
median_mmHg = NaN(length(distention_volumes),1);
median_CSAs = NaN(length(distention_volumes), size(CSA_recordings, 2));

for n = 1: length(distention_volumes)
    idx = find(raw_volumes == distention_volumes(n));
    median_mmHg(n,1) = median(raw_pressures(idx));
    median_CSAs(n,:) = median(CSA_recordings(idx,:),1);
end
% i changed this from using a counter i + 1 to just n because n is
% incremented

x_points = linspace(min(median_mmHg),max(median_mmHg)); %x model does not changes 

% computing distensibility 
for k = 1:16
    if sum(ismember(checked_items,k)) == 1
        signal = median_CSAs(:,k);
        scatter(app.bottom_plot, median_mmHg, signal, 10, colors_list(k,:));
        hold(app.bottom_plot, 'on');
        %plotting quadratic model
        quadratic_model = polyfit(median_mmHg, signal, 2); %2nd degree polynomial
        y_model_quadratic = polyval(quadratic_model, x_points);
        plot(app.bottom_plot, x_points, y_model_quadratic,'Marker',".",'Color',colors_list(k,:));
        %plot linear model to show distensibility; approximate the line up
        %until the plateau 
        idxLimitX = find(signal == max(signal), 1); %find the first instance of a maximum signal 
        linear_X_limit = median_mmHg(idxLimitX);
        index = find(median_mmHg <= linear_X_limit);
        median_mmHg_subset = median_mmHg(index);
        signal_subset = signal(index);
        linear_model = polyfit(median_mmHg_subset,signal_subset,1); %1st degree polynomial is just a straight line
        y_points_linear = polyval(linear_model, median_mmHg_subset);
        plot(app.bottom_plot, median_mmHg_subset, y_points_linear, 'Marker',"_", 'Color', colors_list(k,:));
        distensibility(k) = linear_model(1); %the first output of the polyfit for a 1st degree polynomial is the slope of the line
    end 
end

elecNum = linspace(1,16,16);
elecNum = categorical(elecNum);
colors_list = [0.9020 0.0980 0.2941; 0.2353 0.7059 0.2941; 1.0000 0.8824 0.0980; 0 0.5098 0.7843; 0.9608 0.5098 0.1882;  0.2745 0.9412 0.9412; 0.8235 0.9608 0.2353; 0.9804 0.7451 0.8314; 0 0.5020 0.5020; 0.8627 0.7451 1.0000; 0.6667 0.4314 0.1569; 0.5020 0 0; 0.6667 1.0000 0.7647; 0.5020 0.5020 0; 1.0000 0.8431 0.7059; 0 0 0.5020];
% scatter(app.overallDI_plot, elecNum, distensibility);
for k= 1:size(CSA_recordings,2)
    b = bar(app.overallDI_plot, elecNum(k), distensibility(k), 'FaceColor',"flat");
    set(b, 'FaceColor', colors_list(k,:));
    hold(app.overallDI_plot, 'on');
end
distensibility = rmmissing(distensibility);
yline(app.overallDI_plot, mean(distensibility));
text(app.overallDI_plot, 7, mean(distensibility), 'Mean DI');
yline(app.overallDI_plot, median(distensibility), 'Color', [128/255 128/255 128/255]);
text(app.overallDI_plot, 7, median(distensibility), 'Median DI', 'Color', [128/255 128/255 128/255]);
ylabel(app.overallDI_plot, 'Distensibility (mm^2/mmHg)');
title(app.overallDI_plot, 'Distensibility (mm^2/mmHg) of Esophageal Regions');
xlabel(app.overallDI_plot, 'Electrode Number');
hold(app.overallDI_plot, 'off');   


close all;


        end

        % Button pushed function: PlotCSAasaFunctionofPressureButton
        function PlotCSAasaFunctionofPressureButtonPushed(app, event)
%variables to use in this function; referencing startup command
diameter_recordings = app.diameter_recordings_raw; 
raw_pressures = app.pressures_raw;
raw_volumes = app.volumes_raw;

%converts diameter recordings into CSAs
CSA_recordings = (diameter_recordings./2).^2.*pi(); 
CSA_recordings = [CSA_recordings, mean(CSA_recordings,2)];

%considers user inputs
checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16, app.MeanofElectrodesCheckBox.Value*17];
checked_items(checked_items == 0) = [];

%plotting colors 
colors_list = [0.9020 0.0980 0.2941; 0.2353 0.7059 0.2941; 1.0000 0.8824 0.0980; 0 0.5098 0.7843; 0.9608 0.5098 0.1882;  0.2745 0.9412 0.9412; 0.8235 0.9608 0.2353; 0.9804 0.7451 0.8314; 0 0.5020 0.5020; 0.8627 0.7451 1.0000; 0.6667 0.4314 0.1569; 0.5020 0 0; 0.6667 1.0000 0.7647; 0.5020 0.5020 0; 1.0000 0.8431 0.7059; 0 0 0.5020; 0 0 0];
% https://github.com/jkitchin/matlab-cmu/blob/master/%2Bcmu/colors.m

%performs required calculations of raw data before plotting
distention_volumes = app.DistentionTable.Data(:,1); %distention volumes specified in table
median_mmHg = NaN(length(distention_volumes),1);
median_CSAs = NaN(length(distention_volumes), size(CSA_recordings, 2));

for n = 1: length(distention_volumes)
    idx = find(raw_volumes == distention_volumes(n));
    median_mmHg(n,1) = median(raw_pressures(idx));
    median_CSAs(n,:) = median(CSA_recordings(idx,:),1);
end

x_points = linspace(min(median_mmHg),max(median_mmHg)); %x model does not changes 
count = 1; %starts a counter
% plotting 
for k = checked_items
    signal = median_CSAs(:,k);
    scatter(app.bottom_plot, median_mmHg, signal, 10, colors_list(k,:));
    hold(app.bottom_plot, 'on');
    %plotting quadratic model
    quadratic_model = polyfit(median_mmHg, signal, 2); %2nd degree polynomial
    y_model_quadratic = polyval(quadratic_model, x_points);
    plot(app.bottom_plot, x_points, y_model_quadratic,'Marker',".",'Color',colors_list(k,:));
    %plot linear model to show distensibility; approximate the line up
    %until the plateau 
    idxLimitX = find(signal == max(signal), 1); %find the first instance of a maximum signal 
    linear_X_limit = median_mmHg(idxLimitX);
    index = find(median_mmHg <= linear_X_limit);
    median_mmHg_subset = median_mmHg(index);
    signal_subset = signal(index); %I THINK THIS SHOULD BE QUADRATIC MODEL(INDEX) 3/31/2023
    linear_model = polyfit(median_mmHg_subset,signal_subset,1); %1st degree polynomial is just a straight line
    y_points_linear = polyval(linear_model, median_mmHg_subset);
    plot(app.bottom_plot, median_mmHg_subset, y_points_linear, 'Marker',"_", 'Color', colors_list(k,:));
    distensibility = linear_model(1); %the first output of the polyfit for a 1st degree polynomial is the slope of the line
    distensibility_string = strcat('DI = ', num2str(distensibility,3)); %three significant figures
%     text(app.bottom_plot, median_mmHg_subset(y_points_linear == max(y_points_linear))-1, max(y_points_linear)+1, distensibility_string, 'Color',colors_list(k,:));
    text(app.bottom_plot, min(median_mmHg) + 10*count, 20, distensibility_string, 'Color',colors_list(k,:));
    count = count + 1;
end

ylabel(app.bottom_plot, 'CSA (mm^2)', 'Color', 'k');
app.bottom_plot.YColor = 'k';
% legend(app.bottom_plot, string(checked_items), 'Location','northwest'); %,'NumColumns',2);
title(app.bottom_plot, 'Approximation of Distensibility (mm^2/mmHg)');
xlabel(app.bottom_plot, 'Intrabag Pressure (mmHg)', 'Color', [0.47 0.32 0.66]);
app.bottom_plot.XColor = [0.47 0.32 0.66];
hold(app.bottom_plot, 'off');   


hold(app.top_plot, 'off');

        end

        % Button pushed function: SearchFolderButton
        function SearchFolderButtonPushed(app, event)
%right hand side
% set(app.heatmap_plot, 'Position', [741 55 520 337]);
% set(app.pressureVSvolume_plot, 'Position', [15 5 500 250]);
% set(app.overallDI_plot, 'Position', [15 5 500 250]);
% %left hand side
% set(app.bottom_plot, 'Position', [7 68 530 229]);
% set(app.top_plot, 'Position', [7 296 530 219]);
% set(app.Slider, 'Position', [7 53 530 3]);
% set(app.volumePlot, 'Position', [7 33 530 200]);

file_folder = app.FolderDirectoryEditField.Value;
filePattern = fullfile(file_folder, '*.txt');
txtFiles = dir(filePattern);
file_names = strings([length(txtFiles) 1]);

for k = 1:length(txtFiles)
    file_names(k,1) = txtFiles(k).name;
end
app.file_options.Items = file_names;
app.EnterFileName.Value = file_names(1); %automatically the first entry is populated

        end

        % Drop down opening function: file_options
        function file_optionsDropDownOpening(app, event)
            
        end

        % Value changed function: file_options
        function file_optionsValueChanged(app, event)
value = app.file_options.Value;
app.EnterFileName.Value = value; 
        end

        % Button pushed function: AddDistentionButton
        function AddDistentionButtonPushed(app, event)
raw_volumes = app.volumes_raw;
volumesList = app.DistentionTable.Data(:,1:1);
if isempty(find(raw_volumes == app.DistentionVolumemLEditField.Value,1)) == 0 && sum(volumesList == app.DistentionVolumemLEditField.Value) == 0 %checks if the volume entered is in the dataset and if not currently in list 
    distention_Value = [app.DistentionVolumemLEditField.Value, NaN, NaN, NaN, NaN, NaN, NaN];
    table = app.DistentionTable.Data(:,:); %this is the entire table
    table = [table; distention_Value]; %updates the table
    sorted_values = sort(table(:,1:1));
    sorted_values = rmmissing(sorted_values);
    app.DistentionTable.Data = [sorted_values, NaN(length(sorted_values),6)]; 
else
    app.DistentionTable.Data = app.DistentionTable.Data; %stays the same
end
        end

        % Button pushed function: PopulateTableButton
        function PopulateTableButtonPushed(app, event)
diameter_recordings = app.diameter_recordings_raw; 
raw_volumes = app.volumes_raw;

%converts diameter recordings into CSAs
CSA_recordings = (diameter_recordings./2).^2.*pi(); 
     
distentionTable = app.DistentionTable.Data;
volumeDistentions = distentionTable(:,1:1); %the first column lists all the distention volumes

%considers user inputs
checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16];
checked_items(checked_items == 0) = []; 

if isempty(checked_items) == 1 %if empty
    checked_items = 1:16; % by default all electrodes are checked 
end

tableData = NaN(length(volumeDistentions), 7); %initializes the populated table

for i = 1:length(volumeDistentions)
    idx = find(raw_volumes == volumeDistentions(i));
    %initialize arrays
    distention_CSAs = NaN(size(CSA_recordings));
    distention_Diameters = NaN(size(CSA_recordings));
    electrode_nums = NaN(size(CSA_recordings));

    for j = 1:length(checked_items)
       %populates CSAs
       distention_CSAs(idx,checked_items(j)) = CSA_recordings(idx,checked_items(j));
       %populates diameters
       distention_Diameters(idx,checked_items(j)) = diameter_recordings(idx,checked_items(j));
       electrode_nums(idx,checked_items(j)) = checked_items(j);  
    end
        distention_CSAs = reshape(distention_CSAs,[],1); %reshapes into a one column vector
        distention_CSAs = rmmissing(distention_CSAs); %rounds CSA to two decimal places
        distention_Diameters = reshape(distention_Diameters, [], 1);
        distention_Diameters = rmmissing(distention_Diameters); %rounds diameters to two decimal places 
        electrode_nums = reshape(electrode_nums, [],1);
        electrode_nums = rmmissing(electrode_nums); %electrode nums are integers
        maxPosition = find(distention_Diameters == max(distention_Diameters), 1); %finds the first occurence of the maximum diameter (in case of ties)
        minPosition = find(distention_Diameters == min(distention_Diameters), 1); %finds the first occurence of the minimum diameter (in case of ties) 
        tableData(i,:) = [volumeDistentions(i), mean(distention_CSAs), median(distention_CSAs), distention_Diameters(maxPosition), electrode_nums(maxPosition), distention_Diameters(minPosition), electrode_nums(minPosition)];
end

app.DistentionTable.ColumnFormat = {'shortG' 'bank' 'bank' 'bank' 'shortG' 'bank' 'shortG'}; %formatting as some columns as integers and some with two decimal places

app.DistentionTable.Data = tableData; %updates the table
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
distention_Value = app.DistentionVolumemLEditField.Value;
table = app.DistentionTable.Data;
app.DistentionTable.Data(find(table(:,1) == distention_Value),:) = []; 
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
diameter = app.DiametermmEditField.Value;
CSA = app.CSAmm2EditField.Value; 

if CSA == 0
    app.CSAmm2EditField.Value = round((diameter/2)^2*pi(),2);
else
    app.DiametermmEditField.Value = round(sqrt(CSA*4/pi()),2);
end

        end

        % Button pushed function: PlotVolumeasaFunctionofTimeButton
        function PlotVolumeasaFunctionofTimeButtonPushed(app, event)
% variables saved to application from Start/Upload callback is used here
% distinct_times = app.time_unique; %replace with app.time
% medianVolumes = app.median_fills; %replace with app.volumes_raw

plot(app.volumePlot, app.time/60, app.volumes_raw, '-b');

%plot features and titles
ylabel(app.volumePlot, 'Intrabag Volume (mL)', 'Color', 'b');
xlabel(app.volumePlot, 'Time (min)');
xlim(app.volumePlot, [min(app.time)/60 max(app.time)/60]);
ylim(app.volumePlot, [min(app.volumes_raw)-5 max(app.volumes_raw)+5]);
title(app.volumePlot, 'Intrabag Volume vs Time');
app.volumePlot.YColor = 'b';

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
diameter_recordings = app.diameter_recordings_raw; 
raw_volumes = app.volumes_raw;

%converts diameter recordings into CSAs
CSA_recordings = (diameter_recordings./2).^2.*pi(); 
  
%considers user inputs
checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16];
checked_items(checked_items == 0) = []; 

if isempty(checked_items) == 1 %if empty
    checked_items = 1:16; % by default all electrodes are checked 
end

distentionVolumes = app.DistentionVolumesmLListBox.Value;
distentionVolumes = str2double(distentionVolumes);
distentionVolumes = sort(distentionVolumes); %sorts in ascending order

% finds the number of rows to display in table 
numRows = 0; %initalizes count
for i = 1:length(distentionVolumes)
    idx = find(raw_volumes == distentionVolumes(i));
    numRows = numRows + length(idx);
end
%initializes data to display 
tableData = NaN(numRows, 6);
count = 1; %initiates a counter for table indexes

for i = 1:length(distentionVolumes)
    idx = find(raw_volumes == distentionVolumes(i));
    %initialize arrays
%     distention_CSAs = NaN(size(CSA_recordings));
    distention_Diameters = NaN(size(CSA_recordings));
    electrode_nums = NaN(size(CSA_recordings));
%     balloonFill = NaN(size(CSA_recordings));
    for j = 1:length(checked_items)
       %populates CSAs
%        distention_CSAs(idx,checked_items(j)) = CSA_recordings(idx,checked_items(j));
       %populates diameters
       distention_Diameters(idx,checked_items(j)) = diameter_recordings(idx,checked_items(j));
       electrode_nums(idx,checked_items(j)) = checked_items(j);  
%        balloonFill(idx, checked_items(j)) = distentionVolumes(i);
    end
%     distention_Diameters = rmmissing(distention_Diameters, 2); %removes columns that are NaN
    meanD = mean(distention_Diameters, 2, "omitnan");
    meanD = rmmissing(meanD); %omits rows that are NaN 
    medianD = median(distention_Diameters, 2, "omitnan");
    medianD = rmmissing(medianD);
    minD = min(distention_Diameters, [], 2);
    minD = rmmissing(minD);
    volumeFill = distentionVolumes(i)*ones(length(idx),1); %1st column
    tableData(count:count + length(idx) - 1,:) = [volumeFill, meanD, medianD, minD, meanD - minD, medianD - minD];
    count = count + length(idx);
end
%tableData = rmmissing()
app.tableForStats.ColumnFormat = {'shortG' 'bank' 'bank' 'bank' 'bank' 'bank'}; %formatting as some columns as integers and some with two decimal places
app.tableForStats.Data = tableData; %updates the table   

%runs a t test for mean - min = 0
[~, p1] = ttest(tableData(:,5)); %one sample t test
% [~, p1] = ttest(tableData(:,2), tableData(:,4)); % paired sample t test
app.p_mean_min.Value = round(p1,5);
app.average_mean_min.Value = round(mean(tableData(:,5)),2);

% runs a t test for median - min = 0
[~, p2] = ttest(tableData(:,6));
% [~, p2] = ttest(tableData(:,3), tableData(:,4)); 
app.p_median_min.Value = round(p2,5);
app.average_median_min.Value = round(mean(tableData(:,6)),2);

        end

        % Button pushed function: CopytoClipboardButton
        function CopytoClipboardButtonPushed(app, event)
% file_folder = app.FolderDirectoryEditField.Value;
% filePattern = fullfile(file_folder, '*.txt');
% txtFiles = dir(filePattern);
% file_names = strings([length(txtFiles) 1]);
% 
% for k = 1:length(txtFiles)
%     file_names(k,1) = txtFiles(k).name;
% end


% app.fileName = app.EnterFileName.Value; %this is the name of the file
T = [app.average_mean_min.Value, app.p_mean_min.Value, app.average_median_min.Value, app.p_median_min.Value];
% writematrix(T, 'StatisticalAnalysis.xlsx', "Range", 'D2:G2');
clipboard('copy',T);

% writetable(T,'myPatientData.dat','WriteRowNames',true)  
% type 'myPatientData.dat'
% writetable(T,'myData.xls','Sheet',1,'Range','B2:F6')
        end

        % Button pushed function: GenerateSummaryofDiametersButton
        function GenerateSummaryofDiametersButtonPushed(app, event)
%considers user inputs
% checked_items = [app.Electrode1CheckBox.Value * 1, app.Electrode2CheckBox.Value * 2, app.Electrode3CheckBox.Value * 3, app.Electrode4CheckBox.Value * 4, app.Electrode5CheckBox.Value * 5, app.Electrode6CheckBox.Value * 6, app.Electrode7CheckBox.Value * 7, app.Electrode8CheckBox.Value * 8, app.Electrode9CheckBox.Value * 9, app.Electrode10CheckBox.Value * 10, app.Electrode11CheckBox.Value * 11, app.Electrode12CheckBox.Value * 12, app.Electrode13CheckBox.Value * 13, app.Electrode14CheckBox.Value * 14, app.Electrode15CheckBox.Value * 15, app.Electrode16CheckBox.Value * 16];
% checked_items(checked_items == 0) = []; 
% 
% if isempty(checked_items) == 1 %if empty
%     checked_items = 1:16; % by default all electrodes are checked 
% end

tableData = app.tableForStats.Data;
volumesInput = unique(tableData(:,1)); %the first column contains the unique volumes. eg. 30,40,50,60,70
distentionStats = zeros(5,2); %this initializes a matrix to store mean-min median-min values for the different distention volumes.
volumesList = [30,40,50,60,70];
for i = 1:length(volumesInput)
    index = find(tableData(:,1) == volumesInput(i));
    idxMatrix = find(volumesList == volumesInput(i)); 
    distentionStats(idxMatrix,1) = mean(tableData(index,5)); %the 5th column contains statistics for mean - min
    distentionStats(idxMatrix,2) = mean(tableData(index,6)); %the 6th column contains statistics for median - min
end

% Mean - Min average of results
app.Volume30ccMean_Min.Value = round(distentionStats(1,1),2);
app.Volume40ccMean_Min.Value = round(distentionStats(2,1),2);
app.Volume50ccMean_Min.Value = round(distentionStats(3,1),2); 
app.Volume60ccMean_Min.Value = round(distentionStats(4,1),2);
app.Volume70ccMean_Min.Value = round(distentionStats(5,1),2);

%Median - Min average of results 
app.Volume30ccMedian_Min.Value = round(distentionStats(1,2),2);
app.Volume40ccMedian_Min.Value = round(distentionStats(2,2),2);
app.Volume50ccMedian_Min.Value = round(distentionStats(3,2),2); 
app.Volume60ccMedian_Min.Value = round(distentionStats(4,2),2);
app.Volume70ccMedian_Min.Value = round(distentionStats(5,2),2);

%% generate table to display the confidence intervals of mean, median, and minimum diameters at different distentions
tableSummary = NaN(length(volumesList), 4); %this is 5 rows by 4 columns
for i = 1:length(volumesInput)
    index = find(tableData(:,1) == volumesInput(i));
%     [~, ~, meanCI, ~] = ttest(tableData(index, 2)); 
%     [~, ~, medianCI, ~] = ttest(tableData(index, 3));     
%     [~, ~, minCI, ~] = ttest(tableData(index, 4)); 
    meanDiameter = mean(tableData(index, 2)); 
    medianDiameter = mean(tableData(index, 3));     
    minimumDiameter = mean(tableData(index, 4)); 
    tableSummary(i,:) = [volumesInput(i), meanDiameter, medianDiameter, minimumDiameter];
    
end
app.diameterSummarytable.ColumnFormat = {'shortG' 'bank' 'bank' 'bank'}; %formatting as some columns as integers and some with two decimal places
app.diameterSummarytable.Data = tableSummary;


        end

        % Button pushed function: CopytoClipboardButton_2
        function CopytoClipboardButton_2Pushed(app, event)
dataToCopy = app.diameterSummarytable.Data;
% clipboard('copy',dataToCopy); 
num2clip(dataToCopy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.heatmap_plot = uiaxes(app.UIFigure);
            title(app.heatmap_plot, 'Title')
            xlabel(app.heatmap_plot, 'X')
            ylabel(app.heatmap_plot, 'Y')
            zlabel(app.heatmap_plot, 'Z')
            app.UserInputPanel = uipanel(app.UIFigure);
            app.UserInputPanel.Title = 'User Input';
            app.FolderDirectoryEditFieldLabel = uilabel(app.UserInputPanel);
            app.FolderDirectoryEditFieldLabel.Text = 'Folder Directory';
            app.FolderDirectoryEditField = uieditfield(app.UserInputPanel, 'text');
            app.file_options = uidropdown(app.UserInputPanel);
            app.file_options.DropDownOpeningFcn = createCallbackFcn(app, @file_optionsDropDownOpening, true);
            app.file_options.ValueChangedFcn = createCallbackFcn(app, @file_optionsValueChanged, true);
            app.EntertxtfilenameandhitstartLabel = uilabel(app.UserInputPanel);
            app.EntertxtfilenameandhitstartLabel.Text = 'Enter .txt file name and hit start!';
            app.EnterFileName = uieditfield(app.UserInputPanel, 'text');
            app.SearchFolderButton = uibutton(app.UserInputPanel, 'push');
            app.SearchFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFolderButtonPushed, true);
            app.SearchFolderButton.Text = 'Search Folder';
            app.StartUploadFIleButton = uibutton(app.UserInputPanel, 'push');
            app.StartUploadFIleButton.ButtonPushedFcn = createCallbackFcn(app, @StartUploadFIleButtonPushed, true);
            app.StartUploadFIleButton.Text = 'Start/Upload FIle';
            app.Lamp = uilamp(app.UserInputPanel);
            app.TimeRange_Text = uilabel(app.UserInputPanel);
            app.TimeRange_Text.Text = 'Time Range (min)';
            app.FromLabel = uilabel(app.UserInputPanel);
            app.FromLabel.Text = 'From:';
            app.time1_Entry = uieditfield(app.UserInputPanel, 'numeric');
            app.ToLabel = uilabel(app.UserInputPanel);
            app.ToLabel.Text = 'To:';
            app.time2_Entry = uieditfield(app.UserInputPanel, 'numeric');
            app.PlayVideoButton = uibutton(app.UserInputPanel, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.SaveFrameButton = uibutton(app.UserInputPanel, 'push');
            app.SaveFrameButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFrameButtonPushed, true);
            app.SaveFrameButton.Text = 'Save Frame';
            app.TimeminEditFieldLabel = uilabel(app.UserInputPanel);
            app.TimeminEditFieldLabel.Text = 'Time (min)';
            app.TimeminEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.SelecttoPlotPanel = uipanel(app.UIFigure);
            app.SelecttoPlotPanel.Title = 'Select to Plot';
            app.Electrode1CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode1CheckBox.Text = 'Electrode 1';
            app.Electrode2CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode2CheckBox.Text = 'Electrode 2';
            app.Electrode3CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode3CheckBox.Text = 'Electrode 3';
            app.Electrode4CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode4CheckBox.Text = 'Electrode 4';
            app.Electrode5CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode5CheckBox.Text = 'Electrode 5';
            app.Electrode6CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode6CheckBox.Text = 'Electrode 6';
            app.Electrode7CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode7CheckBox.Text = 'Electrode 7';
            app.Electrode8CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode8CheckBox.Text = 'Electrode 8';
            app.Electrode9CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode9CheckBox.Text = 'Electrode 9';
            app.Electrode10CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode10CheckBox.Text = 'Electrode 10';
            app.Electrode11CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode11CheckBox.Text = 'Electrode 11';
            app.Electrode12CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode12CheckBox.Text = 'Electrode 12';
            app.Electrode13CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode13CheckBox.Text = 'Electrode 13';
            app.Electrode14CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode14CheckBox.Text = 'Electrode 14';
            app.Electrode15CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode15CheckBox.Text = 'Electrode 15';
            app.Electrode16CheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.Electrode16CheckBox.Text = 'Electrode 16';
            app.MeanofElectrodesCheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.MeanofElectrodesCheckBox.Text = 'Mean of Electrodes';
            app.PressureCheckBox = uicheckbox(app.SelecttoPlotPanel);
            app.PressureCheckBox.Text = 'Pressure';
            app.PlotSignalsasaFunctionofTimeButton = uibutton(app.SelecttoPlotPanel, 'push');
            app.PlotSignalsasaFunctionofTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignalsasaFunctionofTimeButtonPushed, true);
            app.PlotSignalsasaFunctionofTimeButton.Text = {'Plot Signals as a Function of Time'; ''};
            app.PlotCSAasaFunctionofPressureButton = uibutton(app.SelecttoPlotPanel, 'push');
            app.PlotCSAasaFunctionofPressureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCSAasaFunctionofPressureButtonPushed, true);
            app.PlotCSAasaFunctionofPressureButton.Text = 'Plot CSA as a Function of Pressure';
            app.PopulateTableButton = uibutton(app.SelecttoPlotPanel, 'push');
            app.PopulateTableButton.ButtonPushedFcn = createCallbackFcn(app, @PopulateTableButtonPushed, true);
            app.PopulateTableButton.Text = 'Populate Table';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntrabagPressureasaFunctionofIntrabagVolumeTab = uitab(app.TabGroup);
            app.IntrabagPressureasaFunctionofIntrabagVolumeTab.Title = 'Intrabag Pressure as a Function of Intrabag Volume';
            app.pressureVSvolume_plot = uiaxes(app.IntrabagPressureasaFunctionofIntrabagVolumeTab);
            title(app.pressureVSvolume_plot, 'Title')
            xlabel(app.pressureVSvolume_plot, 'X')
            ylabel(app.pressureVSvolume_plot, 'Y')
            zlabel(app.pressureVSvolume_plot, 'Z')
            app.OverallDistensibilityTab = uitab(app.TabGroup);
            app.OverallDistensibilityTab.Title = 'Overall Distensibility ';
            app.overallDI_plot = uiaxes(app.OverallDistensibilityTab);
            title(app.overallDI_plot, 'Title')
            xlabel(app.overallDI_plot, 'X')
            ylabel(app.overallDI_plot, 'Y')
            zlabel(app.overallDI_plot, 'Z')
            app.ShowEsophagealTopographyPlotButton = uibutton(app.UIFigure, 'push');
            app.ShowEsophagealTopographyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEsophagealTopographyPlotButtonPushed, true);
            app.ShowEsophagealTopographyPlotButton.Text = 'Show Esophageal Topography Plot';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TableofDistentionVolumes = uitab(app.TabGroup2);
            app.TableofDistentionVolumes.Title = 'Table of Distention Volumes';
            app.volumePlot = uiaxes(app.TableofDistentionVolumes);
            title(app.volumePlot, 'Title')
            xlabel(app.volumePlot, 'X')
            ylabel(app.volumePlot, 'Y')
            zlabel(app.volumePlot, 'Z')
            app.DistentionTable = uitable(app.TableofDistentionVolumes);
            app.DistentionTable.ColumnName = {'Distention Volume (mL)'; 'Mean CSA (mm^2)'; 'Median CSA (mm^2)'; 'Max Diameter (mm)'; 'Electrode #'; 'Min Diameter (mm)'; 'Electrode #'};
            app.DistentionTable.RowName = {};
            app.DistentionTable.ColumnEditable = [true false false false];
            app.AddDistentionButton = uibutton(app.TableofDistentionVolumes, 'push');
            app.AddDistentionButton.ButtonPushedFcn = createCallbackFcn(app, @AddDistentionButtonPushed, true);
            app.AddDistentionButton.Text = 'Add';
            app.DistentionVolumemLEditFieldLabel = uilabel(app.TableofDistentionVolumes);
            app.DistentionVolumemLEditFieldLabel.Text = 'Distention Volume (mL)';
            app.DistentionVolumemLEditField = uieditfield(app.TableofDistentionVolumes, 'numeric');
            app.RemoveButton = uibutton(app.TableofDistentionVolumes, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.DiameterCSACalculatorPanel = uipanel(app.TableofDistentionVolumes);
            app.DiameterCSACalculatorPanel.Title = 'Diameter <--> CSA Calculator';
            app.DiametermmEditFieldLabel = uilabel(app.DiameterCSACalculatorPanel);
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.DiametermmEditField = uieditfield(app.DiameterCSACalculatorPanel, 'numeric');
            app.CSAmm2EditFieldLabel = uilabel(app.DiameterCSACalculatorPanel);
            app.CSAmm2EditFieldLabel.Text = 'CSA (mm^2)';
            app.CSAmm2EditField = uieditfield(app.DiameterCSACalculatorPanel, 'numeric');
            app.ConvertButton = uibutton(app.DiameterCSACalculatorPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.PlotVolumeasaFunctionofTimeButton = uibutton(app.TableofDistentionVolumes, 'push');
            app.PlotVolumeasaFunctionofTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVolumeasaFunctionofTimeButtonPushed, true);
            app.PlotVolumeasaFunctionofTimeButton.Text = 'Plot Volume as a Function of Time';
            app.DiameterAnalysisTab = uitab(app.TabGroup2);
            app.DiameterAnalysisTab.Title = 'Diameter Analysis';
            app.tableForStats = uitable(app.DiameterAnalysisTab);
            app.tableForStats.ColumnName = {'Volume'; 'Mean Diameter (mm)'; 'Median Diameter (mm)'; 'Minimum Diameter (mm)'; 'Mean - Min'; 'Median - Min'};
            app.tableForStats.RowName = {};
            app.DistentionVolumesmLListBoxLabel = uilabel(app.DiameterAnalysisTab);
            app.DistentionVolumesmLListBoxLabel.Text = 'Distention Volumes (mL)';
            app.DistentionVolumesmLListBox = uilistbox(app.DiameterAnalysisTab);
            app.DistentionVolumesmLListBox.Items = {'30', '40', '50', '60', '70'};
            app.DistentionVolumesmLListBox.Multiselect = 'on';
            app.DistentionVolumesmLListBox.Value = {'30'};
            app.AnalyzeButton = uibutton(app.DiameterAnalysisTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.H0MeanMin0EditFieldLabel = uilabel(app.DiameterAnalysisTab);
            app.H0MeanMin0EditFieldLabel.Text = 'H0: Mean - Min = 0';
            app.p_mean_min = uieditfield(app.DiameterAnalysisTab, 'numeric');
            app.HypothesisLabel = uilabel(app.DiameterAnalysisTab);
            app.HypothesisLabel.Text = 'Hypothesis';
            app.H0MedianMin0EditFieldLabel = uilabel(app.DiameterAnalysisTab);
            app.H0MedianMin0EditFieldLabel.Text = 'H0: Median - Min = 0';
            app.p_median_min = uieditfield(app.DiameterAnalysisTab, 'numeric');
            app.pValueLabel = uilabel(app.DiameterAnalysisTab);
            app.pValueLabel.Text = 'p-Value';
            app.AverageLabel = uilabel(app.DiameterAnalysisTab);
            app.AverageLabel.Text = 'Average';
            app.average_mean_min = uieditfield(app.DiameterAnalysisTab, 'numeric');
            app.average_median_min = uieditfield(app.DiameterAnalysisTab, 'numeric');
            app.CopytoClipboardButton = uibutton(app.DiameterAnalysisTab, 'push');
            app.CopytoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoClipboardButtonPushed, true);
            app.CopytoClipboardButton.Text = 'Copy to Clipboard';
            app.SummaryofDiametersatDistentionVolumesTab = uitab(app.TabGroup2);
            app.SummaryofDiametersatDistentionVolumesTab.Title = 'Summary of Diameters at Distention Volumes';
            app.ccEditFieldLabel = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccEditFieldLabel.Text = '30 cc';
            app.Volume30ccMean_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel.Text = '40 cc';
            app.Volume40ccMean_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_2 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_2.Text = '50 cc';
            app.Volume50ccMean_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_3 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_3.Text = '60 cc';
            app.Volume60ccMean_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_4 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_4.Text = '70 cc';
            app.Volume70ccMean_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.AveragesofMeanMinDiametersLabel = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.AveragesofMeanMinDiametersLabel.Text = 'Averages of Mean - Min Diameters';
            app.ccEditFieldLabel_2 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccEditFieldLabel_2.Text = '30 cc';
            app.Volume30ccMedian_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_5 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_5.Text = '40 cc';
            app.Volume40ccMedian_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_6 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_6.Text = '50 cc';
            app.Volume50ccMedian_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_7 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_7.Text = '60 cc';
            app.Volume60ccMedian_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.ccLabel_8 = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.ccLabel_8.Text = '70 cc';
            app.Volume70ccMedian_Min = uieditfield(app.SummaryofDiametersatDistentionVolumesTab, 'numeric');
            app.AveragesofMedianMinDiametersLabel = uilabel(app.SummaryofDiametersatDistentionVolumesTab);
            app.AveragesofMedianMinDiametersLabel.Text = 'Averages of Median - Min Diameters';
            app.GenerateSummaryofDiametersButton = uibutton(app.SummaryofDiametersatDistentionVolumesTab, 'push');
            app.GenerateSummaryofDiametersButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSummaryofDiametersButtonPushed, true);
            app.GenerateSummaryofDiametersButton.Text = 'Generate Summary of Diameters';
            app.diameterSummarytable = uitable(app.SummaryofDiametersatDistentionVolumesTab);
            app.diameterSummarytable.ColumnName = {'Volume (cc)'; 'Mean Diameter (mm)'; 'Median Diameter (mm)'; 'Minimum Diameter (mm)'};
            app.diameterSummarytable.RowName = {};
            app.CopytoClipboardButton_2 = uibutton(app.SummaryofDiametersatDistentionVolumesTab, 'push');
            app.CopytoClipboardButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopytoClipboardButton_2Pushed, true);
            app.CopytoClipboardButton_2.Text = 'Copy to Clipboard';
            app.ElectrodeSignalsandDistensibilityPlotsTab = uitab(app.TabGroup2);
            app.ElectrodeSignalsandDistensibilityPlotsTab.Title = 'Electrode Signals and Distensibility Plots ';
            app.top_plot = uiaxes(app.ElectrodeSignalsandDistensibilityPlotsTab);
            title(app.top_plot, 'Title')
            xlabel(app.top_plot, 'X')
            ylabel(app.top_plot, 'Y')
            zlabel(app.top_plot, 'Z')
            app.bottom_plot = uiaxes(app.ElectrodeSignalsandDistensibilityPlotsTab);
            title(app.bottom_plot, 'Title')
            xlabel(app.bottom_plot, 'X')
            ylabel(app.bottom_plot, 'Y')
            zlabel(app.bottom_plot, 'Z')
            app.Slider = uislider(app.ElectrodeSignalsandDistensibilityPlotsTab);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.TimeRange_Text_2 = uilabel(app.ElectrodeSignalsandDistensibilityPlotsTab);
            app.TimeRange_Text_2.Text = 'Time (min)';
            app.MedianCSACheckBox = uicheckbox(app.UIFigure);
            app.MedianCSACheckBox.Text = 'Median CSA';
            app.MaximumDiameterCheckBox = uicheckbox(app.UIFigure);
            app.MaximumDiameterCheckBox.Text = 'Maximum Diameter';
            app.MaxDiametermmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDiametermmEditFieldLabel.Text = 'Max Diameter (mm)';
            app.MaxDiametermmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEETFLIP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end